classdef Capture_Data_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Capture_Data_New            matlab.ui.Figure
        TopCameraLabel              matlab.ui.control.Label
        BottomCameraLabel           matlab.ui.control.Label
        CenterCameraLabel           matlab.ui.control.Label
        MotionsettingsPanel         matlab.ui.container.Panel
        ArmsLabel                   matlab.ui.control.Label
        MotorLabel                  matlab.ui.control.Label
        SerialPortDropDownLabel     matlab.ui.control.Label
        SerialPortDropDown_arm      matlab.ui.control.DropDown
        SerialPortDropDown_2Label   matlab.ui.control.Label
        SerialPortDropDown_motor    matlab.ui.control.DropDown
        LoadButton_arm              matlab.ui.control.Button
        LoadButton_motor            matlab.ui.control.Button
        LoadButton_arm_open         matlab.ui.control.Button
        LoadButton_arm_close        matlab.ui.control.Button
        SpeedEditFieldLabel         matlab.ui.control.Label
        SpeedEditField              matlab.ui.control.EditField
        ServoSwitchLabel            matlab.ui.control.Label
        ServoSwitch                 matlab.ui.control.Switch
        DirectionSwitchLabel        matlab.ui.control.Label
        DirectionSwitch             matlab.ui.control.Switch
        Lamp_load_arm               matlab.ui.control.Lamp
        Lamp_load_motor             matlab.ui.control.Lamp
        RunButton_motor             matlab.ui.control.StateButton
        AlResetButton               matlab.ui.control.Button
        CamerasettingsPanel         matlab.ui.container.Panel
        LoadCamerasButton           matlab.ui.control.Button
        StatusLamp_load_Camera      matlab.ui.control.Lamp
        ExposureusEditFieldLabel    matlab.ui.control.Label
        ExposureusEditField         matlab.ui.control.EditField
        GammaEditFieldLabel         matlab.ui.control.Label
        GammaEditField              matlab.ui.control.EditField
        RotationangleDropDownLabel  matlab.ui.control.Label
        RotationangleDropDown       matlab.ui.control.DropDown
        ResetButton                 matlab.ui.control.Button
        Load_cam_lamp_status        matlab.ui.control.Label
        CapturesavingsettingsPanel  matlab.ui.container.Panel
        imagesCEditFieldLabel       matlab.ui.control.Label
        imagesCEditField            matlab.ui.control.EditField
        RevtimesEditFieldLabel      matlab.ui.control.Label
        RevtimesEditField           matlab.ui.control.EditField
        Capture_3Button             matlab.ui.control.Button
        Save_3Button                matlab.ui.control.Button
        ImagepairfromtopandbottomcamerasLabel  matlab.ui.control.Label
        cam12_status                matlab.ui.control.Label
        Capture_12Button            matlab.ui.control.Button
        CaptureNewButton            matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        PrevButton                  matlab.ui.control.Button
        NextButton                  matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        EditField_count             matlab.ui.control.NumericEditField
        UIAxes_1                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global T count motor_speed direction binn ss
            global vid_1 vid_2 vid_3 Ang old_gamma old_Ang S_gamma please_wait
            global n_images3 save_dir3 selected_dir rev_time stop_position Dim_12 Dim_3 pleaseWait3 pleaseWait12
            global Images
            
            
            %reset all 
            instrreset;
            imaqreset;
            
            
            % load motor libraries
            a=libfunctions('EziMOTIONPlusRx64');

            if length(a)>1
                unloadlibrary('EziMOTIONPlusRx64'); 
                loadlibrary('EziMOTIONPlusRx64', @FAS_EziMOTIONPlusR); 
            else
                loadlibrary('EziMOTIONPlusRx64', @FAS_EziMOTIONPlusR); 
            end


            % Set default values
            
            count = 1;
            motor_speed = 4800;
            stop_position = 100000; % for 1 rev
            direction = 1; %1 for clockwise and -1 for anticlockwise
            [Ang,old_Ang] = deal(3);
            [old_gamma,S_gamma] = deal(0.4545);
            
            ss = 0;
            binn = 4;
            T = [1.79386, -0.64138, -0.12276;...
                    -.37305, 1.67433,-0.27112;...
                    0.10253,-0.72658,1.65402];% from matrix vision camera
            [vid_1, vid_2, vid_3] = deal(struct([]));
            Dim_12 = round([2464/binn,2056/binn,3]);
            Dim_3 = round([4096/binn,2176/binn,3]);
            
            selected_dir = '';
            save_dir3 = '';
            n_images3 = 36;
            rev_time = 20.5;
            
            % set default values to respective fields
            app.SpeedEditField.Value = num2str(motor_speed);
            app.imagesCEditField.Value = num2str(n_images3);
            app.RevtimesEditField.Value = num2str(rev_time);
            app.cam12_status.Text = num2str(ss);
            app.EditField_count.Value = count;
            app.RotationangleDropDown.Value = '270';
            app.GammaEditField.Value = num2str(S_gamma);
            app.DirectionSwitch.Value = 'L';
            
            drawnow
            
            please_wait = imresize(rgb2gray(imread('Please_Wait.png')),(1/binn));
            sz1 = size(please_wait);
            pleaseWait3 = padarray(please_wait, round(0.5*(Dim_3(1:2)-sz1)));
            pleaseWait12 = padarray(please_wait, round(0.5*(Dim_12(1:2)-sz1)));
            
            Images = cell(1,n_images3);
            
            for i =1:n_images3
                Images{i} = zeros(Dim_3,"uint8");
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            global obj_arm
            delete(app.Capture_Data_New);
            
            try
                fclose(obj_arm);
            catch
                disp('Arm closed..')
            end
            
            imaqreset;
            instrreset;
            clear;
            clc;
        end

        % Value changed function: ServoSwitch
        function ServoSwitchValueChanged(app, event)
            global servo_state motor_port
            servo_state = app.ServoSwitch.Value;
            if strcmp(servo_state, 'On')
                servo_val = 1;
                disp('Servo connected');
            else
                servo_val = 0;
                disp('Servo Disconnected');
            end
            
            calllib('EziMOTIONPlusRx64', 'FAS_ServoEnable', int32(motor_port), int32(0), int32(servo_val));
            
            
        end

        % Value changed function: SerialPortDropDown_arm
        function SerialPortDropDown_armValueChanged(app, event)
            global arm_value
            arm_value = str2double(app.SerialPortDropDown_arm.Value);
            disp(['Arm serial port = ',num2str(arm_value)]);
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
            global motor_speed
            motor_speed = str2double(app.SpeedEditField.Value);
            disp(['Motor speed = ',num2str(motor_speed)]);
            
        end

        % Value changed function: imagesCEditField
        function imagesCEditFieldValueChanged(app, event)
            global n_images3
            n_images3 = str2double(app.imagesCEditField.Value);
            
        end

        % Button pushed function: LoadButton_arm
        function LoadButton_armPushed(app, event)
            global arm_value obj_arm
            
            app.Lamp_load_arm.Color = [1.00,1.00,0.00]; %yellow
            app.LoadButton_arm.Text = 'Loading...';
            obj_arm = instrfind('Type', 'serial', 'Port', ['COM',num2str(arm_value)], 'Tag', '');
            drawnow
            if isempty(obj_arm)
                obj_arm = serial(['COM',num2str(arm_value)]);
            else
                fclose(obj_arm);
                obj_arm = obj_arm(1);
            end
            
            % Connect to instrument object, obj1.
            try
                fopen(obj_arm);
                app.Lamp_load_arm.Color = [0.00,1.00,0.00];
            catch
                app.Lamp_load_arm.Color = [1.00,0.00,0.00];
            end
            app.LoadButton_arm.Text = 'Done...';
            pause(0.2)
            app.LoadButton_arm.Text = 'Load';
            drawnow
            
        end

        % Button pushed function: LoadButton_arm_open
        function LoadButton_arm_openPushed(app, event)
            global obj_arm
            try
                fwrite(obj_arm, '#5#O#4');
                fwrite(obj_arm, '#5#O#4');
            catch
                disp('Error moving arm')
            end
            
        end

        % Button pushed function: LoadButton_arm_close
        function LoadButton_arm_closePushed(app, event)
            global obj_arm
            try
                fwrite(obj_arm, '#5#C#4');
                fwrite(obj_arm, '#5#C#4');
            catch
                disp('Error moving arm')
            end
        end

        % Button pushed function: LoadButton_motor
        function LoadButton_motorPushed(app, event)
            global motor_port
            
            
            try
                app.Lamp_load_motor.Color = [1.00,1.00,0.00]; %yellow
                app.LoadButton_motor.Text = 'Loading...';
                drawnow
                calllib('EziMOTIONPlusRx64', 'FAS_Connect',int32(motor_port), int32(115200));
                Check=calllib('EziMOTIONPlusRx64', 'FAS_IsSlaveExist',int32(motor_port), int32(0));
                
                if Check==1
                    app.Lamp_load_motor.Color = [0.00,1.00,0.00];
                    disp('Motor Connected Successfully!')
                    app.LoadButton_motor.Text = 'Done...';
                    pause(0.2)
                    app.LoadButton_motor.Text = 'Load';
                    drawnow
                else
                    app.Lamp_load_motor.Color = [1.00,0.00,0.00];
                    disp('Could NOT connect Motor, try agian')
                    app.LoadButton_motor.Text = 'Error!';
                    pause(3)
                    app.LoadButton_motor.Text = 'Load';
                    drawnow
                end
            catch
                app.Lamp_load_motor.Color = [1.00,0.00,0.00];
                disp('Unknown error... Please check your code')
                app.LoadButton_motor.Text = 'Error!';
                pause(3)
                app.LoadButton_motor.Text = 'Load';
                drawnow
            end
        end

        % Value changed function: SerialPortDropDown_motor
        function SerialPortDropDown_motorValueChanged(app, event)
            global motor_port
            motor_port = str2double(app.SerialPortDropDown_motor.Value);
            
        end

        % Value changed function: DirectionSwitch
        function DirectionSwitchValueChanged(app, event)
            global direction
            dir_value = app.DirectionSwitch.Value;
            
            if strcmp(dir_value,'L')
                direction = 1;
            else
                direction = -1;
            end
            
        end

        % Value changed function: RunButton_motor
        function RunButton_motorValueChanged(app, event)
            global direction motor_port motor_speed stop_position rev_time delay_time n_images3
            
            start_value = app.RunButton_motor.Value;
            num_rev = 2.5; % max number of revolutions = 2.5
            if start_value == 1
                Position_INC1 = round(stop_position*direction*num_rev);
                load_motor = calllib('EziMOTIONPlusRx64', 'FAS_MoveSingleAxisIncPos', int32(motor_port), int32(0), int32(Position_INC1), int32(motor_speed));
                
                if load_motor == 0
                    disp('Motor Connected Successfully!');
                    app.RunButton_motor.Text = 'Stop';
                else
                    disp('Cannot Connect motor... Please reset and try again')
                end
            else
                try
                    calllib('EziMOTIONPlusRx64', 'FAS_MoveStop', int32(motor_port), int32(0));
                    disp('Motor Stopped!');
                    app.RunButton_motor.Text = 'Run';
                catch
                    disp('Error in stopping motor')
                end
            end
            
            if motor_speed > 1
                delay_time = round((rev_time/n_images3),4);
            end
        end

        % Button pushed function: AlResetButton
        function AlResetButtonPushed(app, event)
            global motor_port
            
            try
                calllib('EziMOTIONPlusRx64', 'FAS_ServoAlarmReset', int32(motor_port), int32(0));
                disp('Motor servo reset success...')
            catch
                disp('Motor servo reset Failed!!!...')
            end
        end

        % Button pushed function: LoadCamerasButton
        function LoadCamerasButtonPushed(app, event)
            global vid_1 vid_2 vid_3 src_1 src_2 src_3 binn exp fr1 hImage_1 hImage_2 hImage_3
            global Ang S_gamma old_exp old_gamma old_Ang I1 I2 Dim_12 Dim_3 pleaseWait3 pleaseWait12

            
            imshow(pleaseWait12,[],'Parent',app.UIAxes_1)
            imshow(pleaseWait12,[],'Parent',app.UIAxes_2)
            imshow(pleaseWait3,[],'Parent',app.UIAxes_3)
            
            axis(app.UIAxes_1,'tight')
            axis(app.UIAxes_2,'tight')
            axis(app.UIAxes_3,'tight')
            drawnow limitrate
            
            %% Load cameras
            try
                app.StatusLamp_load_Camera.Color = [1.00,1.00,0.07];
                app.Load_cam_lamp_status.Text = 'Loading...';
                drawnow
                
                % bottom camera
                vid_1 = videoinput('gentl',2,'BayerRG8');
                src_1 = getselectedsource(vid_1);
                vid_1.FramesPerTrigger = 1;
                
                % top camera
                vid_2 = videoinput('gentl',1,'BayerRG8');
                src_2 = getselectedsource(vid_2);
                vid_2.FramesPerTrigger = 1;
                
                % center camera
                vid_3 = videoinput('gentl',3,'BayerRG8');
                src_3 = getselectedsource(vid_3);
                vid_3.FramesPerTrigger = 1;
                exp = src_3.ExposureTime;
                fr1 = src_3.mvResultingFrameRate;
                
                old_exp = exp;
                old_gamma = S_gamma;
                old_Ang = Ang;
                
                app.ExposureusEditField.Value = num2str(exp);
                app.StatusLamp_load_Camera.Color = [0.0, 1.0, 0.0];
                app.Load_cam_lamp_status.Text = 'Done...';
                drawnow
               
                [src_1.ExposureTime, src_2.ExposureTime, src_3.ExposureTime] = deal(exp);
                
                try
                    I_1 = getsnapshot(vid_1);
                    I_2 = getsnapshot(vid_2);
                    
                    I1 = rotMvCorrectBin(I_1,binn,Ang,S_gamma,T);
                    I2 = rotMvCorrectBin(I_2,binn,Ang,S_gamma,T);
                catch
                    [I1,I2] = deal(zeros(Dim_12));
                end
                
            catch
                disp('Error while loading')
            end
            
            try
                disp('Previewing cameras....')
                
            %% preview cameras
                hImage_2 = image(zeros(Dim_12), 'Parent', app.UIAxes_1);% top
                hImage_1 = image(zeros(Dim_12), 'Parent', app.UIAxes_2);% bottom
                hImage_3 = image(zeros(Dim_3), 'Parent', app.UIAxes_3);% center
            
                setappdata(hImage_1,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
                preview(vid_1, hImage_1);
                axis(app.UIAxes_1,'tight')
                drawnow limitrate

%                 imshow(I1,[],'Parent',app.UIAxes_1)
%                 axis(app.UIAxes_1,'tight')
                
                setappdata(hImage_2,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
                preview(vid_2, hImage_2);
                axis(app.UIAxes_2,'tight')
                drawnow limitrate

%                 imshow(I2,[],'Parent',app.UIAxes_2)
%                 axis(app.UIAxes_2,'tight')                

                setappdata(hImage_3,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
                preview(vid_3, hImage_3);
                axis(app.UIAxes_3,'tight')
                drawnow limitrate
                disp('Done...')
            catch
                disp('Error previewing cameras....')
            end
            
        end

        % Value changed function: ExposureusEditField
        function ExposureusEditFieldValueChanged(app, event)
            global exp vid_1 src_1 vid_2 src_2 vid_3 src_3 hImage_1 hImage_2 hImage_3
            global T Ang S_gamma zz I1 I2 Dim_12
            
            exp = str2double(app.ExposureusEditField.Value);
            
            [src_1.ExposureTime, src_2.ExposureTime, src_3.ExposureTime] = deal(exp);
            
            try
                I_1 = getsnapshot(vid_1);
                I_2 = getsnapshot(vid_2);
                
                I1 = rotMvCorrectBin(I_1,binn,Ang,S_gamma,T);
                I2 = rotMvCorrectBin(I_2,binn,Ang,S_gamma,T);
            catch
                [I1,I2] = deal(zeros(Dim_12));
            end
            
            %% preview cameras
            
            setappdata(hImage_1,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
            preview(vid_1, hImage_1);
            axis(app.UIAxes_1,'tight')
            drawnow limitrate
%             imshow(I1,[],'Parent',app.UIAxes_1)
%             axis(app.UIAxes_1,'tight')
            
            setappdata(hImage_2,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
            preview(vid_2, hImage_2);
            axis(app.UIAxes_2,'tight')
            drawnow limitrate
%             imshow(I2,[],'Parent',app.UIAxes_2)
%             axis(app.UIAxes_2,'tight')
            
            setappdata(hImage_3,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
            preview(vid_3, hImage_3);
            axis(app.UIAxes_3,'tight')
            drawnow limitrate
            zz = {T, Ang, S_gamma, hImage_1, hImage_2};            
        end

        % Value changed function: RotationangleDropDown
        function RotationangleDropDownValueChanged(app, event)
            global Ang
            ang_v = str2double(app.RotationangleDropDown.Value);
            if ang_v==0
                Ang = 0;
            elseif ang_v == 90
                Ang = 1;
            elseif ang_v == 180
                Ang = 2;
            elseif ang_v == 270
                Ang = 3;
            end
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            global S_gamma
            S_gamma = str2double(app.GammaEditField.Value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global exp vid_1 src_1 vid_2 src_2 vid_3 src_3 hImage_1 hImage_2 hImage_3
            global Ang S_gamma old_exp old_gamma old_Ang T zz I1 I2
            
            Ang = old_Ang;
            exp = old_exp;
            S_gamma = old_gamma;
            
            [src_1.ExposureTime, src_2.ExposureTime, src_3.ExposureTime] = deal(exp);
            
            try
                I_1 = getsnapshot(vid_1);
                I_2 = getsnapshot(vid_2);
                
                I1 = rotMvCorrectBin(I_1,binn,Ang,S_gamma,T);
                I2 = rotMvCorrectBin(I_2,binn,Ang,S_gamma,T);
            catch
                [I1,I2] = deal(zeros(Dim_12));
            end
            % preview cameras
            
            setappdata(hImage_1,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
            preview(vid_1, hImage_1);
            axis(app.UIAxes_1,'tight')
            drawnow limitrate
            
            setappdata(hImage_2,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
            preview(vid_2, hImage_2);
            axis(app.UIAxes_2,'tight')
            drawnow limitrate
            
            setappdata(hImage_3,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
            preview(vid_3, hImage_3);
            axis(app.UIAxes_3,'tight')
            drawnow limitrate
            
            zz = {T,hImage_2,hImage_1};
        end

        % Value changed function: RevtimesEditField
        function RevtimesEditFieldValueChanged(app, event)
            global rev_time
            rev_time = str2double(app.RevtimesEditField.Value);
        end

        % Button pushed function: Capture_3Button
        function Capture_3ButtonPushed(app, event)
            global vid_1 vid_2 vid_3 src_3 rev_time
            global T Ang S_gamma n_images3 count
            global I1 I2 please_wait Images fr
            global motor_port stop_position direction motor_speed Dim_3 servo_state
            
            closepreview(vid_1)
            closepreview(vid_2)
            closepreview(vid_3)
            
            sz1 = size(please_wait);
            pleaseWait= padarray(please_wait, round(0.5*(Dim_3(1:2)-sz1)));
            
            if (length(size(I1))<2)&&(select_cam1==1)&&(select_cam2==1)
                I1 = getsnapshot(vid_1);
                I1 = rotMvCorrect(I1,Ang,S_gamma,T);
                
                I2 = getsnapshot(vid_2);
                I2 = rotMvCorrect(I2,Ang,S_gamma,T);
            end
            
            imshow(I1,[],'Parent',app.UIAxes_1)
            axis(app.UIAxes_1,'tight')
            drawnow limitrate
            imshow(I2,[],'Parent',app.UIAxes_2)
            axis(app.UIAxes_2,'tight')
            drawnow limitrate
            imshow(pleaseWait,[],'Parent',app.UIAxes_3)
            axis(app.UIAxes_3,'tight')
            drawnow limitrate

            
            src_3.AcquisitionFrameRate = 15;
            
            fr = src_3.mvResultingFrameRate;
            
            nframe_rate = 10;
            if fr<10
                errordlg('Camera Framerate of  is too low')
            else
                if fr > nframe_rate
                    nframe_rate = 0.8*fr;
                end
                disp(strcat('Capture fps = ',num2str(nframe_rate)))
                src_3.AcquisitionFrameRate = nframe_rate;
        
                total_frames = round(nframe_rate*rev_time);
        
                vid_3.LoggingMode = 'memory';
                triggerconfig(vid_3, 'immediate')
                vid_3.TimerPeriod = rev_time; 
        
                vid_3.FramesPerTrigger = n_images3; 
                vid_3.FrameGrabInterval = (total_frames/n_images3);
        
                disp('+ [Capturing images from Cam 3]...')
                
                %% motor must rotate clock wise
                direction = 1; 
                app.DirectionSwitch.Value = 'L';
                
                %% servo must be on
                if strcmp(servo_state, 'Off')
                    app.ServoSwitch.Value = 'On';
                    calllib('EziMOTIONPlusRx64', 'FAS_ServoEnable', int32(motor_port), int32(0), int32(1));
                end
                
                Position_INC1 = stop_position*direction;
                
                %% start motor
                
                load_motor = calllib('EziMOTIONPlusRx64', 'FAS_MoveSingleAxisIncPos', int32(motor_port), int32(0),...
                int32(Position_INC1), int32(motor_speed));
                
                if load_motor == 0
                    pause(1) % wait for 1 before start of capture
                    
                    % Initiate the Image acquisition.
                    start(vid_3);tic
            
                    % Wait for acquisition to end.
                    wait(vid_3, (1.2*rev_time));toc
            
                    % Determine the number frames acquired.
                    framesCaptured = vid_3.FramesAcquired;
                    
                    %% stop motor
                    calllib('EziMOTIONPlusRx64', 'FAS_MoveStop', int32(motor_port), int32(0));
            
                    images = getdata(vid_3);
                else
                    disp('Motor error')   
                    return
                end
                
                disp(strcat(num2str(framesCaptured),'_Frames have been captured from Cam 3]...'))
        
                disp('+ [Correcting color of images]...')
                images = gpuArray(images);
                for i = 1:n_images3
                    Images{i} = gather(rotMvCorrect(images(:,:,:,i),Ang,S_gamma,T));
                end
                imshow(Images{count},[],'Parent', app.UIAxes_3)
                axis(app.UIAxes_3,'tight')
                drawnow limitrate
                disp('+ Done...')
            end
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            global count Images n_images3
            ii = count-1;
            if (ii > 0) && ii <= (n_images3)
                imshow(Images{ii},[],'Parent',app.UIAxes_3)
                drawnow limitrate
                app.EditField_count.Value = ii;
            elseif ii < 1
                imshow(Images{1},[],'Parent',app.UIAxes_3)
                drawnow limitrate
                app.EditField_count.Value = 1;
            elseif ii>n_images3
                imshow(Images{n_images3},[],'Parent',app.UIAxes_3)
                drawnow limitrate
                app.EditField_count.Value = n_images3;
            end
            axis(app.UIAxes_3,'tight')
            count = ii;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            global count Images n_images3
            ii = count+1;
            if (ii > 0) && ii <= (n_images3)
                imshow(Images{ii},[],'Parent',app.UIAxes_3)
                drawnow limitrate
                app.EditField_count.Value = ii;
            elseif ii < 1
                imshow(Images{1},[],'Parent',app.UIAxes_3)
                drawnow limitrate
                app.EditField_count.Value = 1;
            elseif ii>n_images3
                imshow(Images{n_images3},[],'Parent',app.UIAxes_3)
                drawnow limitrate
                app.EditField_count.Value = n_images3;
            end
            axis(app.UIAxes_3,'tight')
            count = ii;
        end

        % Button pushed function: Save_3Button
        function Save_3ButtonPushed(app, event)
            global Images count n_images3 save_dir3
            
            save_dir3 = uigetdir('F:\Image data');
            
            % reorder images
            if count > 1
                n_1 = count;
                n_2 = n_images3-n_1+1;
                
                Im_ordered = cell(1,n_images3);
                
                 if n_1 == n_images3
                    Im_ordered{1} = Images{n_images3};
                    Im_ordered(2:n_images3) = Images(1:(n_images3-1));
                 elseif n_1 == 1
                    Im_ordered = Images;
                 else
                    Im_ordered(1:n_2) = Images(n_1:n_images3);%images after selected #1
                    Im_ordered((n_2+1):n_images3) = Images(1:(n_1-1));%images before selected #1
                 end
                
                Images = Im_ordered;
                
                count = 1;
                app.EditField_count.Value = count;
            end
            
            disp('+ [Saving images]...')
            
            for i = 1:n_images3 
                baseFileName = (strcat(num2str(i),'.tif'));
                fullFileName = fullfile(save_dir3, baseFileName);
                imwrite(Images{i}, fullFileName);
                disp(strcat('Saving image_',num2str(i),'_out of_', num2str(n_images3)))
            end
        
        disp('+ [Done]...')
        end

        % Button pushed function: Capture_12Button
        function Capture_12ButtonPushed(app, event)
            global vid_1 vid_2
            global T Ang S_gamma binn zz
            global ss new_exp selected_dir I1 I2 
            
            Exp = round(new_exp);
            
%             [src_2.ExposureTime,src_1.ExposureTime] = deal(Exp);
            
            I1 = getsnapshot(vid_1);
            I1 = rotMvCorrect(I1,Ang,S_gamma,T);
            
            imshow(I1,[],'Parent', app.UIAxes_1)
            
            axis(app.UIAxes_1,'tight')
            drawnow limitrate
            
            I2 = getsnapshot(vid_2);
            I2 = rotMvCorrect(I2,Ang,S_gamma,T);
            
            imshow(I2,[],'Parent',app.UIAxes_2)
            axis(app.UIAxes_2,'tight')
            drawnow limitrate
            
            answer = questdlg('Do you want to save the images from cam_1 & 2?', ...
            	'Save Images 1&2 dialog', ...
            	'Yes','No','Yes');
            
            if strcmp(answer,'Yes')
                ss = ss+1;
                
                if ss==1
                    selected_dir = uigetdir('F:\Image data');
                end
                
                if ss == 1 || ss == 3
                    imwrite(I1,strcat(selected_dir,'\',num2str(ss*2),'.tif'));
                    imwrite(I2,strcat(selected_dir,'\',num2str((ss*2) - 1),'.tif'));
                elseif ss == 2 || ss ==4
                    imwrite(I1,strcat(selected_dir,'\',num2str((ss*2) - 1),'.tif'));
                    imwrite(I2,strcat(selected_dir,'\',num2str(ss*2),'.tif'));
                end
                
                app.cam12_status.Text = num2str(ss);
                
            end
            if ss == 4
                app.Capture_12Button.BackgroundColor = [1.0, 0.0, 0.0];
                app.Capture_12Button.Enable = 'off';
            end
           
            imshow(I1,[],'Parent',app.UIAxes_1)
            axis(app.UIAxes_1,'tight')
            drawnow limitrate
            imshow(I2,[],'Parent',app.UIAxes_2)
            axis(app.UIAxes_2,'tight')
            drawnow limitrate
%             hImage_1=image(zeros(Dim_12), 'Parent', app.UIAxes_1);
%             setappdata(hImage_1,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
%             preview(vid_1, hImage_1);
%             axis(app.UIAxes_1,'tight')
%             drawnow limitrate
%        
%             hImage_2=image(zeros(Dim_12), 'Parent', app.UIAxes_1);
%             setappdata(hImage_2,'UpdatePreviewWindowFcn',@mypreview_fcn_rotCCBin);
%             preview(vid_2, hImage_2);
%             axis(app.UIAxes_2,'tight')
%             drawnow limitrate
            
            zz = binn;
                   
        end

        % Button pushed function: CaptureNewButton
        function CaptureNewButtonPushed(app, event)
            global count ss

            ss = 0;
            count = 1;
            
            app.Capture_12Button.BackgroundColor = [0.96,0.96,0.96];
            app.cam12_status.Text = num2str(ss);
            app.Capture_12Button.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Capture_Data_New = uifigure('Visible', 'off');
            app.Capture_Data_New.Name = 'Capture_Data_New';
            app.TopCameraLabel = uilabel(app.Capture_Data_New);
            app.TopCameraLabel.Text = 'Top Camera';
            app.BottomCameraLabel = uilabel(app.Capture_Data_New);
            app.BottomCameraLabel.Text = 'Bottom Camera';
            app.CenterCameraLabel = uilabel(app.Capture_Data_New);
            app.CenterCameraLabel.Text = 'Center Camera';
            app.MotionsettingsPanel = uipanel(app.Capture_Data_New);
            app.MotionsettingsPanel.Title = 'Motion settings';
            app.ArmsLabel = uilabel(app.MotionsettingsPanel);
            app.ArmsLabel.Text = 'Arms';
            app.MotorLabel = uilabel(app.MotionsettingsPanel);
            app.MotorLabel.Text = 'Motor';
            app.SerialPortDropDownLabel = uilabel(app.MotionsettingsPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown_arm = uidropdown(app.MotionsettingsPanel);
            app.SerialPortDropDown_arm.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.SerialPortDropDown_arm.Editable = 'on';
            app.SerialPortDropDown_arm.ValueChangedFcn = createCallbackFcn(app, @SerialPortDropDown_armValueChanged, true);
            app.SerialPortDropDown_arm.Value = '1';
            app.SerialPortDropDown_2Label = uilabel(app.MotionsettingsPanel);
            app.SerialPortDropDown_2Label.Text = 'Serial  Port';
            app.SerialPortDropDown_motor = uidropdown(app.MotionsettingsPanel);
            app.SerialPortDropDown_motor.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.SerialPortDropDown_motor.Editable = 'on';
            app.SerialPortDropDown_motor.ValueChangedFcn = createCallbackFcn(app, @SerialPortDropDown_motorValueChanged, true);
            app.SerialPortDropDown_motor.Value = '1';
            app.LoadButton_arm = uibutton(app.MotionsettingsPanel, 'push');
            app.LoadButton_arm.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_armPushed, true);
            app.LoadButton_arm.Text = 'Load';
            app.LoadButton_motor = uibutton(app.MotionsettingsPanel, 'push');
            app.LoadButton_motor.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_motorPushed, true);
            app.LoadButton_motor.Text = 'Load';
            app.LoadButton_arm_open = uibutton(app.MotionsettingsPanel, 'push');
            app.LoadButton_arm_open.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_arm_openPushed, true);
            app.LoadButton_arm_open.Text = 'Open';
            app.LoadButton_arm_close = uibutton(app.MotionsettingsPanel, 'push');
            app.LoadButton_arm_close.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_arm_closePushed, true);
            app.LoadButton_arm_close.Text = 'Close';
            app.SpeedEditFieldLabel = uilabel(app.MotionsettingsPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.MotionsettingsPanel, 'text');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.ServoSwitchLabel = uilabel(app.MotionsettingsPanel);
            app.ServoSwitchLabel.Text = 'Servo';
            app.ServoSwitch = uiswitch(app.MotionsettingsPanel, 'slider');
            app.ServoSwitch.ValueChangedFcn = createCallbackFcn(app, @ServoSwitchValueChanged, true);
            app.DirectionSwitchLabel = uilabel(app.MotionsettingsPanel);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.DirectionSwitch = uiswitch(app.MotionsettingsPanel, 'slider');
            app.DirectionSwitch.Items = {'R', 'L'};
            app.DirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DirectionSwitchValueChanged, true);
            app.DirectionSwitch.Value = 'R';
            app.Lamp_load_arm = uilamp(app.MotionsettingsPanel);
            app.Lamp_load_motor = uilamp(app.MotionsettingsPanel);
            app.RunButton_motor = uibutton(app.MotionsettingsPanel, 'state');
            app.RunButton_motor.ValueChangedFcn = createCallbackFcn(app, @RunButton_motorValueChanged, true);
            app.RunButton_motor.Text = 'Run';
            app.AlResetButton = uibutton(app.MotionsettingsPanel, 'push');
            app.AlResetButton.ButtonPushedFcn = createCallbackFcn(app, @AlResetButtonPushed, true);
            app.AlResetButton.Text = 'Al. Reset';
            app.CamerasettingsPanel = uipanel(app.Capture_Data_New);
            app.CamerasettingsPanel.Title = 'Camera settings';
            app.LoadCamerasButton = uibutton(app.CamerasettingsPanel, 'push');
            app.LoadCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCamerasButtonPushed, true);
            app.LoadCamerasButton.Text = 'Load Cameras';
            app.StatusLamp_load_Camera = uilamp(app.CamerasettingsPanel);
            app.ExposureusEditFieldLabel = uilabel(app.CamerasettingsPanel);
            app.ExposureusEditFieldLabel.Text = 'Exposure (us)';
            app.ExposureusEditField = uieditfield(app.CamerasettingsPanel, 'text');
            app.ExposureusEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureusEditFieldValueChanged, true);
            app.ExposureusEditField.Value = '0';
            app.GammaEditFieldLabel = uilabel(app.CamerasettingsPanel);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.CamerasettingsPanel, 'text');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.RotationangleDropDownLabel = uilabel(app.CamerasettingsPanel);
            app.RotationangleDropDownLabel.Text = 'Rotation angle';
            app.RotationangleDropDown = uidropdown(app.CamerasettingsPanel);
            app.RotationangleDropDown.Items = {'0', '90', '180', '270'};
            app.RotationangleDropDown.ValueChangedFcn = createCallbackFcn(app, @RotationangleDropDownValueChanged, true);
            app.RotationangleDropDown.Value = '0';
            app.ResetButton = uibutton(app.CamerasettingsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Load_cam_lamp_status = uilabel(app.CamerasettingsPanel);
            app.Load_cam_lamp_status.Text = 'Status';
            app.CapturesavingsettingsPanel = uipanel(app.Capture_Data_New);
            app.CapturesavingsettingsPanel.Title = 'Capture & saving settings';
            app.imagesCEditFieldLabel = uilabel(app.CapturesavingsettingsPanel);
            app.imagesCEditFieldLabel.Text = '# images C';
            app.imagesCEditField = uieditfield(app.CapturesavingsettingsPanel, 'text');
            app.imagesCEditField.ValueChangedFcn = createCallbackFcn(app, @imagesCEditFieldValueChanged, true);
            app.RevtimesEditFieldLabel = uilabel(app.CapturesavingsettingsPanel);
            app.RevtimesEditFieldLabel.Text = 'Rev. time (s)';
            app.RevtimesEditField = uieditfield(app.CapturesavingsettingsPanel, 'text');
            app.RevtimesEditField.ValueChangedFcn = createCallbackFcn(app, @RevtimesEditFieldValueChanged, true);
            app.Capture_3Button = uibutton(app.CapturesavingsettingsPanel, 'push');
            app.Capture_3Button.ButtonPushedFcn = createCallbackFcn(app, @Capture_3ButtonPushed, true);
            app.Capture_3Button.Text = 'Capture_3';
            app.Save_3Button = uibutton(app.CapturesavingsettingsPanel, 'push');
            app.Save_3Button.ButtonPushedFcn = createCallbackFcn(app, @Save_3ButtonPushed, true);
            app.Save_3Button.Text = 'Save_3';
            app.ImagepairfromtopandbottomcamerasLabel = uilabel(app.CapturesavingsettingsPanel);
            app.ImagepairfromtopandbottomcamerasLabel.Text = 'Image pair from top and bottom cameras';
            app.cam12_status = uilabel(app.CapturesavingsettingsPanel);
            app.cam12_status.Text = '0';
            app.Capture_12Button = uibutton(app.CapturesavingsettingsPanel, 'push');
            app.Capture_12Button.ButtonPushedFcn = createCallbackFcn(app, @Capture_12ButtonPushed, true);
            app.Capture_12Button.Text = 'Capture_12';
            app.CaptureNewButton = uibutton(app.CapturesavingsettingsPanel, 'push');
            app.CaptureNewButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureNewButtonPushed, true);
            app.CaptureNewButton.Text = 'Capture New';
            app.ExitButton = uibutton(app.CapturesavingsettingsPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.PrevButton = uibutton(app.Capture_Data_New, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.Capture_Data_New, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Label = uilabel(app.Capture_Data_New);
            app.Label.Text = '#';
            app.EditField_count = uieditfield(app.Capture_Data_New, 'numeric');
            app.UIAxes_1 = uiaxes(app.Capture_Data_New);
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.UIAxes_2 = uiaxes(app.Capture_Data_New);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.Capture_Data_New);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            % Show the figure after all components are created
            app.Capture_Data_New.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Capture_Data_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Capture_Data_New)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Capture_Data_New)
        end
    end
end